classdef CyclicVoltammetry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConcentrationmMEditField       matlab.ui.control.EditField
        ConcentrationmMEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        CurrentuAEditField_2           matlab.ui.control.EditField
        CurrentuAEditField_2Label      matlab.ui.control.Label
        ImportButton                   matlab.ui.control.Button
        PotentiostatConfigurePanel     matlab.ui.container.Panel
        CutoffFrequenceHzEditField     matlab.ui.control.EditField
        CutoffFrequenceHzEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField          matlab.ui.control.EditField
        SampleRateHzEditFieldLabel     matlab.ui.control.Label
        StepmVEditField                matlab.ui.control.EditField
        StepmVEditFieldLabel           matlab.ui.control.Label
        RepeatTimesEditField           matlab.ui.control.EditField
        RepeatTimesEditFieldLabel      matlab.ui.control.Label
        EndVoltagemVEditField          matlab.ui.control.EditField
        EndVoltagemVLabel              matlab.ui.control.Label
        StartVoltagemVEditField        matlab.ui.control.EditField
        StartVoltagemVLabel            matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        CurrentuAEditField             matlab.ui.control.EditField
        CurrentuAEditFieldLabel        matlab.ui.control.Label
        VoltagemVEditField             matlab.ui.control.EditField
        VoltagemVEditFieldLabel        matlab.ui.control.Label
        RESETButton                    matlab.ui.control.StateButton
        Lamp                           matlab.ui.control.Lamp
        StatusEditField                matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ELECTROCHEMICALPOTENTIOSTATLabel  matlab.ui.control.Label
        ExportFileButton               matlab.ui.control.Button
        DesignbyHDuyncLabel            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        STARTButton                    matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset;
            app.s = serialport('COM5',115200);
            configureTerminator(app.s,"CR/LF"); 

            a=instrhwinfo('serial');
            if (isempty(a.SerialPorts)==1)
                app.StatusEditField.Value="DISCONNECT";
                app.Lamp.Color='red';
            else
                app.StatusEditField.Value="CONNECT";
                app.Lamp.Color='green';
            end
            datacursormode(app.UIFigure, 'on');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            global dienap dongdien Cycle StartVoltage EndVoltage Step Freq CoFreq Excel t m
            flush(app.s);
            i=0;
            a=instrhwinfo('serial');
            if (isempty(a.SerialPorts)==1)
                app.StatusEditField.Value="DISCONNECT";
                app.Lamp.Color='red';
            else
                app.StatusEditField.Value="CONNECT";
                app.Lamp.Color='green';
            end
            StartVoltage=str2double(app.StartVoltagemVEditField.Value);
            EndVoltage=str2double(app.EndVoltagemVEditField.Value);
            Step=str2double(app.StepmVEditField.Value);
            Freq=str2double(app.SampleRateHzEditField.Value);
            CoFreq=str2double(app.CutoffFrequenceHzEditField.Value);
            Cycle=str2double(app.RepeatTimesEditField.Value);
            if (StartVoltage<-792)
                errordlg('StartVoltage>= -792mV','Error Potentiostat');
            elseif (EndVoltage>792)
                errordlg('EndVoltage<= 792mV','Error Potentiosat');
            elseif (EndVoltage<StartVoltage)
                errordlg('StartVoltage <EndVoltage','Error Potentiosat');
            elseif (Cycle>5)
                errordlg('RepeatTimes <=5','Error Potentiosat');
            elseif (CoFreq>=Freq/2)
                errordlg('Cutoff Frequence<SampleRate/2','Error Potentiosat');                
            else
            
                t=num2str(StartVoltage)+"|"+num2str(EndVoltage)+"_"+num2str(Step)+"?"+num2str(Freq/2)+'#'+num2str(Cycle);
            %app.StatusEditField.Value=t;
                writeline(app.s,t);
                pause(1);
                m=((EndVoltage-StartVoltage)/Step+1)*Cycle*2;
                while i<m
                i=i+1;
                dienap(i)=str2double(readline(app.s));
                app.VoltagemVEditField.Value=num2str(dienap(i));
                dongdien(i)=str2double(readline(app.s));
                app.CurrentuAEditField.Value=num2str(dongdien(i));
                %pause(0.5);
                end
            
                plot(app.UIAxes,dienap,lowpass(dongdien,CoFreq,Freq));
                Excel=[dienap;lowpass(dongdien,CoFreq,Freq)];                 
                app.UITable.Data=Excel';
            end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ExportFileButton
        function ExportFileButtonPushed(app, event)
            global Excel m StartVoltage EndVoltage Step Freq Cycle CoFreq
            t=m+13;
            z=append('A13:B',num2str(t));
            
            
            FileName = uiputfile('*.xlsx','Save as');
            columnName={'Voltage(mV)','Current(uA)'};
            tenfile={'File name',FileName};
            Mesurementmode={'Mesurement mode','Cyclic Voltammetry'};
            cachdong={'--','--'};
            Start={'Start Voltage',StartVoltage,'[mV]'};
            End={'End Voltage',EndVoltage,'[mV]'};
            Step1={'Step',Step,'[mV]'};
            Freq1={'Sample Rate',Freq,'[Hz]'};
            Cycle1={'Repeat Times',Cycle};
            CoFreq1={'Cutoff Frequence',CoFreq,'[Hz]'};
            DateTime={'Date & Time',datestr(now,'dd mmmm, yyyy'),datestr(now,'HH:MM:SS AM')};
            
            
            writecell(tenfile,FileName,'Range','A1:B1');
            writecell(Mesurementmode,FileName,'Range','A2:B2');
            writecell(cachdong,FileName,'Range','A3:B3');
            writecell(Start,FileName,'Range','A4:C4');
            writecell(End,FileName,'Range','A5:C5');
            writecell(Step1,FileName,'Range','A6:C6');
            writecell(Cycle1,FileName,'Range','A7:B7');
            writecell(CoFreq1,FileName,'Range','A8:C8');
            writecell(Freq1,FileName,'Range','A9:C9');
            writecell(DateTime,FileName,'Range','A10:C10');
            writecell(cachdong,FileName,'Range','A11:B11');
            
            
            writecell(columnName,FileName,'Range','A12:B12');
            writematrix(Excel',FileName,'Range',z);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.s);
            delete(app);
            close all;
            clear all;
        end

        % Value changing function: RepeatTimesEditField
        function RepeatTimesEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global Cycle;
            %Cycle=str2double(changingValue);
        end

        % Value changing function: StartVoltagemVEditField
        function StartVoltagemVEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global StartVoltage;
            %StartVoltage=str2double(changingValue);
            
        end

        % Value changing function: EndVoltagemVEditField
        function EndVoltagemVEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global EndVoltage;
            %EndVoltage=str2double(changingValue);
        end

        % Value changing function: StepmVEditField
        function StepmVEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global Step;
            %Step=str2double(changingValue);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global Freq;
            %Freq=str2double(changingValue);
        end

        % Value changing function: CutoffFrequenceHzEditField
        function CutoffFrequenceHzEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            %global CoFreq;
            %CoFreq=str2double(changingValue);
        end

        % Value changing function: StatusEditField
        function StatusEditFieldValueChanging(app, event)
            global t;
            event.Value=t;
            
        end

        % Value changed function: StatusEditField
        function StatusEditFieldValueChanged(app, event)
            a=instrhwinfo('serial');
            if isempty(a.SerialPorts)
                app.StatusEditField.Value="CONNECT"+serialportlist("available");
            else
                app.StatusEditField.Value="DISCONNECT";
            end
            
        end

        % Value changed function: RESETButton
        function RESETButtonValueChanged(app, event)
            %value = app.RESETButton.Value
            app.StartVoltagemVEditField.Value=num2str("0");
            app.EndVoltagemVEditField.Value=num2str("0");
            app.StepmVEditField.Value=num2str("0");
            app.SampleRateHzEditField.Value=num2str("0");
            app.CutoffFrequenceHzEditField.Value=num2str("0");
            app.RepeatTimesEditField.Value=num2str("0");
            app.VoltagemVEditField.Value=num2str("0");
            app.CurrentuAEditField.Value=num2str("0");
            cla(app.UIAxes);
            Excel=[];                 
            app.UITable.Data=Excel';
            delete(app.s);
            startupFcn(app);
            
        end

        % Value changing function: VoltagemVEditField
        function VoltagemVEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global Excel m StartVoltage EndVoltage Step Freq Cycle CoFreq
            t=m+13;
            z=append('A13:B',num2str(t));
            
            
            FileName = app.FilenameEditField.Value;
            columnName={'Voltage(mV)','Current(uA)'};
            tenfile={'File name',FileName};
            Mesurementmode={'Mesurement mode','Cyclic Voltammetry'};
            cachdong={'--','--'};
            Start={'Start Voltage',StartVoltage,'[mV]'};
            End={'End Voltage',EndVoltage,'[mV]'};
            Step1={'Step',Step,'[mV]'};
            Freq1={'Sample Rate',Freq,'[Hz]'};
            Cycle1={'Repeat Times',Cycle};
            CoFreq1={'Cutoff Frequence',CoFreq,'[Hz]'};
            DateTime={'Date & Time',datestr(now,'dd mmmm, yyyy'),datestr(now,'HH:MM:SS AM')};
            
            
            writecell(tenfile,FileName,'Range','A1:B1');
            writecell(Mesurementmode,FileName,'Range','A2:B2');
            writecell(cachdong,FileName,'Range','A3:B3');
            writecell(Start,FileName,'Range','A4:C4');
            writecell(End,FileName,'Range','A5:C5');
            writecell(Step1,FileName,'Range','A6:C6');
            writecell(Cycle1,FileName,'Range','A7:B7');
            writecell(CoFreq1,FileName,'Range','A8:C8');
            writecell(Freq1,FileName,'Range','A9:C9');
            writecell(DateTime,FileName,'Range','A10:C10');
            writecell(cachdong,FileName,'Range','A11:B11');
            
            
            writecell(columnName,FileName,'Range','A12:B12');
            writematrix(Excel',FileName,'Range',z);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            t = readtable(fullfile(path, file),"Sheet",1);
            x= table2array(t(13:end,1));
            y= table2array(t(13:end,2));
            plot(app.UIAxes,x,lowpass(y,2,50));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Current=str2double(app.CurrentuAEditField_2.Value);
            %Con=(Current-347.36)/6.8943;
            Con=(Current-347.36)/57.8975;
            app.ConcentrationmMEditField.Value=num2str(Con);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cyclic Voltammetry')
            xlabel(app.UIAxes, 'Voltage (mV)')
            ylabel(app.UIAxes, 'Current (uA)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '1.png';
            app.DesignbyHDuyncLabel = uilabel(app.UIFigure);
            app.DesignbyHDuyncLabel.Text = 'Design by: Hà Duyên Đức';
            app.ExportFileButton = uibutton(app.UIFigure, 'push');
            app.ExportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFileButtonPushed, true);
            app.ExportFileButton.Text = 'Export File';
            app.ELECTROCHEMICALPOTENTIOSTATLabel = uilabel(app.UIFigure);
            app.ELECTROCHEMICALPOTENTIOSTATLabel.Text = 'ELECTROCHEMICAL POTENTIOSTAT';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Voltage(mV)'; 'Current(uA)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.ValueChangedFcn = createCallbackFcn(app, @StatusEditFieldValueChanged, true);
            app.StatusEditField.ValueChangingFcn = createCallbackFcn(app, @StatusEditFieldValueChanging, true);
            app.StatusEditField.Value = 'DISCONNECT';
            app.Lamp = uilamp(app.UIFigure);
            app.RESETButton = uibutton(app.UIFigure, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @RESETButtonValueChanged, true);
            app.RESETButton.Text = {'RESET'; ''};
            app.VoltagemVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltagemVEditFieldLabel.Text = 'Voltage (mV)';
            app.VoltagemVEditField = uieditfield(app.UIFigure, 'text');
            app.VoltagemVEditField.ValueChangingFcn = createCallbackFcn(app, @VoltagemVEditFieldValueChanging, true);
            app.VoltagemVEditField.Value = '0';
            app.CurrentuAEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentuAEditFieldLabel.Text = 'Current (uA)';
            app.CurrentuAEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentuAEditField.Value = '0';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'CV.xlsx';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PotentiostatConfigurePanel = uipanel(app.UIFigure);
            app.PotentiostatConfigurePanel.Title = 'Potentiostat Configure';
            app.StartVoltagemVLabel = uilabel(app.PotentiostatConfigurePanel);
            app.StartVoltagemVLabel.Text = 'Start Voltage (mV)';
            app.StartVoltagemVEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.StartVoltagemVEditField.ValueChangingFcn = createCallbackFcn(app, @StartVoltagemVEditFieldValueChanging, true);
            app.StartVoltagemVEditField.Value = '-200';
            app.EndVoltagemVLabel = uilabel(app.PotentiostatConfigurePanel);
            app.EndVoltagemVLabel.Text = 'End Voltage (mV)';
            app.EndVoltagemVEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.EndVoltagemVEditField.ValueChangingFcn = createCallbackFcn(app, @EndVoltagemVEditFieldValueChanging, true);
            app.EndVoltagemVEditField.Value = '600';
            app.RepeatTimesEditFieldLabel = uilabel(app.PotentiostatConfigurePanel);
            app.RepeatTimesEditFieldLabel.Text = 'Repeat Times';
            app.RepeatTimesEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.RepeatTimesEditField.ValueChangingFcn = createCallbackFcn(app, @RepeatTimesEditFieldValueChanging, true);
            app.RepeatTimesEditField.Value = '3';
            app.StepmVEditFieldLabel = uilabel(app.PotentiostatConfigurePanel);
            app.StepmVEditFieldLabel.Text = 'Step (mV)';
            app.StepmVEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.StepmVEditField.ValueChangingFcn = createCallbackFcn(app, @StepmVEditFieldValueChanging, true);
            app.StepmVEditField.Value = '10';
            app.SampleRateHzEditFieldLabel = uilabel(app.PotentiostatConfigurePanel);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.SampleRateHzEditField.Value = '50';
            app.CutoffFrequenceHzEditFieldLabel = uilabel(app.PotentiostatConfigurePanel);
            app.CutoffFrequenceHzEditFieldLabel.Text = 'Cutoff Frequence (Hz)';
            app.CutoffFrequenceHzEditField = uieditfield(app.PotentiostatConfigurePanel, 'text');
            app.CutoffFrequenceHzEditField.ValueChangingFcn = createCallbackFcn(app, @CutoffFrequenceHzEditFieldValueChanging, true);
            app.CutoffFrequenceHzEditField.Value = '2';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CurrentuAEditField_2Label = uilabel(app.UIFigure);
            app.CurrentuAEditField_2Label.Text = {'Current'; '(uA)'};
            app.CurrentuAEditField_2 = uieditfield(app.UIFigure, 'text');
            app.CurrentuAEditField_2.Value = '0';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<=>';
            app.ConcentrationmMEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationmMEditFieldLabel.Text = {'Concentration'; '(mM)'};
            app.ConcentrationmMEditField = uieditfield(app.UIFigure, 'text');
            app.ConcentrationmMEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CyclicVoltammetry

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end